classdef createMask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        IndexSpinnerLabel  matlab.ui.control.Label
        IndexSpinner       matlab.ui.control.Spinner
        AxisButtonGroup    matlab.ui.container.ButtonGroup
        XYButton           matlab.ui.control.RadioButton
        XZButton           matlab.ui.control.RadioButton
        YZButton           matlab.ui.control.RadioButton
        MaskButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        data % Description
        axis
        roi
        h
        ax
        positions
        mask 
    end
    
    methods (Access = private)
        
        function sliced_data = slice_data(app)
            if app.axis == 1
                sliced_data = squeeze(app.data(app.IndexSpinner.Value, :, :));
            elseif app.axis == 2
                sliced_data = squeeze(app.data(:, app.IndexSpinner.Value, :));
            elseif app.axis == 3 
                sliced_data = squeeze(app.data(:, :, app.IndexSpinner.Value));
            end
        end
    
        
        function plotData(app)
            sliceData =  slice_data(app);
            imagesc(app.UIAxes,sliceData);
            pos = app.roi.Position;
            imagesc(app.ax, sliceData);
            app.roi = images.roi.Polygon(app.ax,'Position', pos);
        end
        
        function plotPolygon(app)
            app.roi = images.roi.Polygon(app.UIAxes);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            app.data = data;
            app.mask = ones(size(data));
            app.XYButton.Value = true;
            app.axis = 3;
            app.IndexSpinner.Value = 1;
            assignin('base','app',app);
            app.h = figure;
            imagesc(app.data(:,:,1));
            app.positions = [10 15; 20 15; 20 35; 10 35 ];
            app.ax = findall(app.h, 'type', 'axes');
            app.roi = images.roi.Polygon(app.ax,'Position',app.positions);
            plotData(app)
        end

        % Value changed function: IndexSpinner
        function IndexSpinnerValueChanged(app, event)
            if app.IndexSpinner.Value >0
                if app.IndexSpinner.Value <= size(app.data, app.axis)
                    plotData(app)
                end
            end
        end

        % Selection changed function: AxisButtonGroup
        function AxisButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxisButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'XY')
                app.axis = 3;
            elseif strcmp(selectedButton.Value, 'XZ')
                app.axis = 2;
            else
                app.axis = 1;
            end
            plotData(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            app.positions = app.roi.Position;
            xq = 1:size(app.data,2); yq = 1:size(app.data,1);
            [X, Y] = meshgrid(xq,yq);
            xq = reshape(X,[1,size(X,1)*size(X,2)]);
            yq = reshape(Y,[1,size(X,1)*size(X,2)]);
            [in,~] = inpolygon(yq,xq, app.positions(:,2), app.positions(:,1));
%             P1 = fliplr(app.positions(1,:)); P2 = fliplr(app.positions(2,:)); P3 = fliplr(app.positions(3,:));
%             P12 = P1-P2; P23 = P2-P3; P31 = P3-P1;
%             in = [];
%             for i = 1:length(xq)
%             P = [yq(i),xq(i)];
%             
%             t = sign(det([P31;P23]))*sign(det([P3-P;P23])) >= 0 & ...
%              sign(det([P12;P31]))*sign(det([P1-P;P31])) >= 0 & ...
%              sign(det([P23;P12]))*sign(det([P2-P;P12])) >= 0 ;
%             if t
%                 in(end+1) = i;
%             end
            %end
            app.data(yq(in),xq(in), app.IndexSpinner.Value) = 0;
            app.mask(yq(in),xq(in), app.IndexSpinner.Value) = 0;
            plotData(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.IndexSpinnerLabel = uilabel(app.UIFigure);
            app.IndexSpinnerLabel.Text = 'Index';
            app.IndexSpinner = uispinner(app.UIFigure);
            app.IndexSpinner.ValueChangedFcn = createCallbackFcn(app, @IndexSpinnerValueChanged, true);
            app.AxisButtonGroup = uibuttongroup(app.UIFigure);
            app.AxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxisButtonGroupSelectionChanged, true);
            app.AxisButtonGroup.Title = 'Axis';
            app.XYButton = uiradiobutton(app.AxisButtonGroup);
            app.XYButton.Text = 'XY';
            app.XYButton.Value = true;
            app.XZButton = uiradiobutton(app.AxisButtonGroup);
            app.XZButton.Text = 'XZ';
            app.YZButton = uiradiobutton(app.AxisButtonGroup);
            app.YZButton.Text = 'YZ';
            app.MaskButton = uibutton(app.UIFigure, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
        end
    end

    methods (Access = public)

        % Construct app
        function app = createMask(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end